classdef OpenImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        OptionsButton          matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        SelectImage            matlab.ui.control.Button
        ImageOutput1           matlab.ui.control.Image
        ImageOutput2           matlab.ui.control.Image
        ImageOutput3           matlab.ui.control.Image
        Image2                 matlab.ui.control.Image
        Mask3x3Label           matlab.ui.control.Label
        Mask5x5Label           matlab.ui.control.Label
        Image2_2               matlab.ui.control.Image
        Mask7x7Label           matlab.ui.control.Label
        Image2_3               matlab.ui.control.Image
        Label                  matlab.ui.control.Label
        Mask3x3Label_2         matlab.ui.control.Label
        Mask5x5Label_2         matlab.ui.control.Label
        Mask7x7Label_2         matlab.ui.control.Label
        ImageOutput1_2         matlab.ui.control.Image
        ImageOutput2_2         matlab.ui.control.Image
        ImageOutput3_2         matlab.ui.control.Image
        ImageOutput1_3         matlab.ui.control.Image
        ImageOutput2_3         matlab.ui.control.Image
        ImageOutput3_3         matlab.ui.control.Image
        MinimunFilteringLabel  matlab.ui.control.Label
        MaximunFilteringLabel  matlab.ui.control.Label
        MedianFilteringLabel   matlab.ui.control.Label
    end


    properties (Access = private)
        DialogApp                   % Dialog box app
        CurrentSize = 35;           % Surface sample size
        CurrentColormap = 'Parula'; % Colormap
        
    end

    methods (Access = public)
    
        function updateplot(app, sz, c)
            % Store inputs as properties
            app.CurrentSize = sz;
            app.CurrentColormap = c;
            
            % Update plot 
            Z = peaks(sz);
            surf(app.UIAxes,Z);
            colormap(app.UIAxes,c);
            
            % Re-enable the Plot Options button
            app.OptionsButton.Enable = 'on';
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Call updateplot to display an initial plot
            updateplot(app, app.CurrentSize, app.CurrentColormap)
            path = pwd;
            file = 'img.jpg';
            app.Image.ImageSource = fullfile(path,file);
        end

        % Button pushed function: OptionsButton
        function OptionsButtonPushed(app, event)
            % Disable Plot Options button while dialog is open
            app.OptionsButton.Enable = 'off';
            
            % Open the options dialog and pass inputs
            app.DialogApp = DialogAppExample(app, app.CurrentSize, app.CurrentColormap);
        end

        % Close request function: UIFigure
        function MainAppCloseRequest(app, event)
            % Delete both apps
            delete(app.DialogApp)
            delete(app)
        end

        % Callback function: Image, SelectImage
        function SelectImagePushed(app, event)
        %เมื่อปุ่มถูก เลือกภาพที่ต้องการ ถูกกด
            [file,path] = uigetfile('*.jpg;*.png;*.gif');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
            end
            app.Image.ImageSource = fullfile(path,file); 
            
            %path = pwd;
            %file = 'img.jpg';
            
            app.ImageOutput1.ImageSource = fullfile(path,file);
            app.ImageOutput2.ImageSource = fullfile(path,file);
            app.ImageOutput3.ImageSource = fullfile(path,file);
            
            app.ImageOutput1_2.ImageSource = fullfile(path,file);
            app.ImageOutput2_2.ImageSource = fullfile(path,file);
            app.ImageOutput3_2.ImageSource = fullfile(path,file);
            
            
            app.ImageOutput1_3.ImageSource = fullfile(path,file);
            app.ImageOutput2_3.ImageSource = fullfile(path,file);
            app.ImageOutput3_3.ImageSource = fullfile(path,file);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Display Plot';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @MainAppCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            app.OptionsButton = uibutton(app.UIFigure, 'push');
            app.OptionsButton.ButtonPushedFcn = createCallbackFcn(app, @OptionsButtonPushed, true);
            app.OptionsButton.Text = 'Options';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @SelectImagePushed, true);
            app.SelectImage = uibutton(app.UIFigure, 'push');
            app.SelectImage.ButtonPushedFcn = createCallbackFcn(app, @SelectImagePushed, true);
            app.SelectImage.Text = 'เลือกภาพที่ต้องการ..';
            app.ImageOutput1 = uiimage(app.UIFigure);
            app.ImageOutput2 = uiimage(app.UIFigure);
            app.ImageOutput3 = uiimage(app.UIFigure);
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = 'mask3x3.jpg';
            app.Mask3x3Label = uilabel(app.UIFigure);
            app.Mask3x3Label.Text = 'Mask 3 x 3';
            app.Mask5x5Label = uilabel(app.UIFigure);
            app.Mask5x5Label.Text = 'Mask 5 x 5';
            app.Image2_2 = uiimage(app.UIFigure);
            app.Image2_2.ImageSource = 'mask5x5.jpg';
            app.Mask7x7Label = uilabel(app.UIFigure);
            app.Mask7x7Label.Text = 'Mask 7 x 7';
            app.Image2_3 = uiimage(app.UIFigure);
            app.Image2_3.ImageSource = 'mask7x7.jpg';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ภาพต้นฉบับ';
            app.Mask3x3Label_2 = uilabel(app.UIFigure);
            app.Mask3x3Label_2.Text = 'ภาพผลลัพธ์ Mask 3 x 3';
            app.Mask5x5Label_2 = uilabel(app.UIFigure);
            app.Mask5x5Label_2.Text = 'ภาพผลลัพธ์ Mask 5 x 5';
            app.Mask7x7Label_2 = uilabel(app.UIFigure);
            app.Mask7x7Label_2.Text = 'ภาพผลลัพธ์ Mask 7 x 7';
            app.ImageOutput1_2 = uiimage(app.UIFigure);
            app.ImageOutput2_2 = uiimage(app.UIFigure);
            app.ImageOutput3_2 = uiimage(app.UIFigure);
            app.ImageOutput1_3 = uiimage(app.UIFigure);
            app.ImageOutput2_3 = uiimage(app.UIFigure);
            app.ImageOutput3_3 = uiimage(app.UIFigure);
            app.MinimunFilteringLabel = uilabel(app.UIFigure);
            app.MinimunFilteringLabel.Text = 'Minimun Filtering';
            app.MaximunFilteringLabel = uilabel(app.UIFigure);
            app.MaximunFilteringLabel.Text = 'Maximun Filtering';
            app.MedianFilteringLabel = uilabel(app.UIFigure);
            app.MedianFilteringLabel.Text = 'Median Filtering';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OpenImage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end